classdef taller2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Taller2Label                matlab.ui.control.Label
        GrabarButton                matlab.ui.control.Button
        ReproducirButton            matlab.ui.control.Button
        PausarButton                matlab.ui.control.Button
        DetenerButton               matlab.ui.control.Button
        EfectosdesonidoButtonGroup  matlab.ui.container.ButtonGroup
        EcoButton                   matlab.ui.control.RadioButton
        ReberveracinButton          matlab.ui.control.RadioButton
        FlangingButton              matlab.ui.control.RadioButton
        PanningButton               matlab.ui.control.RadioButton
        TremoloButton               matlab.ui.control.RadioButton
        NingunoButton               matlab.ui.control.RadioButton
        ECOSliderLabel              matlab.ui.control.Label
        ECOSlider                   matlab.ui.control.Slider
        ReberveracinSliderLabel     matlab.ui.control.Label
        ReberveracinSlider          matlab.ui.control.Slider
        TremoloSliderLabel          matlab.ui.control.Label
        TremoloSlider               matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            %Duracion = app.GrabarButton.Value;
            
            Duracion = 2; %tiempo de grabación en segundos
            Fs = 44100; %Frecuencia de muestreo

            %Crear un objeto de audio
            %En este caso es un mircrofono, que se va a muestrear
            %con Fs, una resolución de 16 bits y de un canal.
            %1 es mono 2 estereo

            Entrada = audiorecorder(Fs,16,1);
            %Abrir cuadro de dialogo
            msgbox('Grabando...','Titulo');
            %funcion para capturar el sonido
            recordblocking(Entrada,Duracion);
            msgbox('Finalizo la grabación','titulo');
            %Asignar audio capturado a una variable
            x=getaudiodata(Entrada,'int16');
            %guardar en un archivo de audio
            audiowrite('Sonido.wav',x,Fs);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Taller2Label = uilabel(app.UIFigure);
            app.Taller2Label.Text = 'Taller 2';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.Text = 'Reproducir';
            app.PausarButton = uibutton(app.UIFigure, 'push');
            app.PausarButton.Text = 'Pausar';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.Text = 'Detener';
            app.EfectosdesonidoButtonGroup = uibuttongroup(app.UIFigure);
            app.EfectosdesonidoButtonGroup.Title = 'Efectos de sonido';
            app.EcoButton = uiradiobutton(app.EfectosdesonidoButtonGroup);
            app.EcoButton.Text = 'Eco';
            app.EcoButton.Value = true;
            app.ReberveracinButton = uiradiobutton(app.EfectosdesonidoButtonGroup);
            app.ReberveracinButton.Text = 'Reberveración';
            app.FlangingButton = uiradiobutton(app.EfectosdesonidoButtonGroup);
            app.FlangingButton.Text = 'Flanging';
            app.PanningButton = uiradiobutton(app.EfectosdesonidoButtonGroup);
            app.PanningButton.Text = 'Panning';
            app.TremoloButton = uiradiobutton(app.EfectosdesonidoButtonGroup);
            app.TremoloButton.Text = 'Tremolo';
            app.NingunoButton = uiradiobutton(app.EfectosdesonidoButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.ECOSliderLabel = uilabel(app.UIFigure);
            app.ECOSliderLabel.Text = 'ECO';
            app.ECOSlider = uislider(app.UIFigure);
            app.ReberveracinSliderLabel = uilabel(app.UIFigure);
            app.ReberveracinSliderLabel.Text = 'Reberveración';
            app.ReberveracinSlider = uislider(app.UIFigure);
            app.TremoloSliderLabel = uilabel(app.UIFigure);
            app.TremoloSliderLabel.Text = 'Tremolo';
            app.TremoloSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = taller2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end